classdef covid_visual < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COVID19DataVisualizationUIFigure  matlab.ui.Figure
        StateorRegionListBox       matlab.ui.control.ListBox
        StateorRegionListBoxLabel  matlab.ui.control.Label
        CountryListBox             matlab.ui.control.ListBox
        CountryLabel               matlab.ui.control.Label
        OptionButtonGroup          matlab.ui.container.ButtonGroup
        DailyButton                matlab.ui.control.RadioButton
        CumulativeButton           matlab.ui.control.RadioButton
        DatatoplotButtonGroup      matlab.ui.container.ButtonGroup
        BothButton                 matlab.ui.control.RadioButton
        DeathsButton               matlab.ui.control.RadioButton
        CasesButton                matlab.ui.control.RadioButton
        AveragedofdaysSlider       matlab.ui.control.Slider
        AveragedofdaysLabel        matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CovidData
        Country_Index
        State_Index
        AvgNumOfDays
        AvgCumulatedCases
        AvgCumulatedDeaths
        AvgDailyCases
        AvgDailyDeaths
        IsAverage
    end
    
    methods (Access = public)
        
       function res = find_country_index(app)
            for index = 1:length(app.CovidData.CountryNames)
                if strcmp(app.CovidData.CountryNames{index}, app.CountryListBox.Value)
                    res = index;
                    return;
                end
            end
       end
    end
    
    methods (Access = private)
        
        function plot_data(app)
            index = app.State_Index;
            if (isequal(app.CasesButton.Value, true) && isequal(app.CumulativeButton.Value, true))
                cla(app.UIAxes,'reset');
                if strcmp((app.StateorRegionListBox.Value),"All")
                    figure_title = ['Cumulative Number of Cases in ',char(app.CountryListBox.Value)];
                else
                    figure_title = ['Cumulative Number of Cases in ',char(app.StateorRegionListBox.Value)];
                end
                if isequal(app.IsAverage,1)
                    figure_title = [figure_title,' (',char(string(app.AvgNumOfDays)),'-day mean)'];
                end
                bar(app.UIAxes,app.AvgCumulatedCases(index,:));
                title(app.UIAxes,figure_title);
                mx = max(app.AvgCumulatedCases(index,:));
                set(app.UIAxes,'YLim',[0,mx]);   
                
            elseif (isequal(app.DeathsButton.Value, true) && isequal(app.CumulativeButton.Value, true))
                cla(app.UIAxes,'reset');
                if strcmp((app.StateorRegionListBox.Value),"All")
                    figure_title = ['Cumulative Number of Deaths in ',char(app.CountryListBox.Value)];
                else
                    figure_title = ['Cumulative Number of Deaths in ',char(app.StateorRegionListBox.Value)];
                end
                if isequal(app.IsAverage,1)
                    figure_title = [figure_title,' (',char(string(app.AvgNumOfDays)),'-day mean)'];
                end
                plot(app.UIAxes,app.AvgCumulatedDeaths(index,:));
                title(app.UIAxes,figure_title);                
                mx = max(app.AvgCumulatedDeaths(index,:));
                set(app.UIAxes,'YLim',[0,mx]);  
                
            elseif (isequal(app.BothButton.Value, true) && isequal(app.CumulativeButton.Value, true))
                cla(app.UIAxes,'reset');
                if strcmp((app.StateorRegionListBox.Value),"All")
                    figure_title = ['Cumulative Number of Cases and Deaths in ',char(app.CountryListBox.Value)];
                else
                    figure_title = ['Cumulative Number of Cases and Deaths in ',char(app.StateorRegionListBox.Value)];
                end
                if isequal(app.IsAverage,1)
                    figure_title = [figure_title,' (',char(string(app.AvgNumOfDays)),'-day mean)'];
                end                
                yyaxis(app.UIAxes,'left');
                bar(app.UIAxes,app.AvgCumulatedCases(index,:));
                title(app.UIAxes,figure_title);
                mx = max(app.AvgCumulatedCases(index,:));
                set(app.UIAxes,'YLim',[0,mx]);
                yyaxis(app.UIAxes,'right');
                plot(app.UIAxes,app.AvgCumulatedDeaths(index,:));
                mx = max(app.AvgCumulatedDeaths(index,:));
                set(app.UIAxes,'YLim',[0,mx]); 
                
            elseif (isequal(app.CasesButton.Value, true) && isequal(app.DailyButton.Value, true))
                cla(app.UIAxes,'reset');
                if strcmp((app.StateorRegionListBox.Value),"All")
                    figure_title = ['Daily Number of Cases in ',char(app.CountryListBox.Value)];
                else
                    figure_title = ['Daily Number of Cases in ',char(app.StateorRegionListBox.Value)];
                end
                if isequal(app.IsAverage,1)
                    figure_title = [figure_title,' (',char(string(app.AvgNumOfDays)),'-day mean)'];
                end                
                bar(app.UIAxes,app.AvgDailyCases(index,:));
                title(app.UIAxes,figure_title);
                mx = max(app.AvgDailyCases(index,:));
                set(app.UIAxes,'YLim',[0,mx]); 
                
            elseif (isequal(app.DeathsButton.Value, true) && isequal(app.DailyButton.Value, true))
                cla(app.UIAxes,'reset');
                if strcmp((app.StateorRegionListBox.Value),"All")
                    figure_title = ['Daily Number of Deaths in ',char(app.CountryListBox.Value)];
                else
                    figure_title = ['Daily Number of Deaths in ',char(app.StateorRegionListBox.Value)];
                end
                if isequal(app.IsAverage,1)
                    figure_title = [figure_title,' (',char(string(app.AvgNumOfDays)),'-day mean)'];
                end                
                plot(app.UIAxes,app.AvgDailyDeaths(index,:));
                title(app.UIAxes,figure_title);
                mx = max(app.AvgDailyDeaths(index,:));
                set(app.UIAxes,'YLim',[0,mx]);
                
            elseif (isequal(app.BothButton.Value, true) && isequal(app.DailyButton.Value, true))
                cla(app.UIAxes,'reset');
                if strcmp((app.StateorRegionListBox.Value),"All")
                    figure_title = ['Daily Number of Cases and Deaths in ',char(app.CountryListBox.Value)];
                else
                    figure_title = ['Daily Number of Cases and Deaths in ',char(app.StateorRegionListBox.Value)];
                end
                if isequal(app.IsAverage,1)
                    figure_title = [figure_title,' (',char(string(app.AvgNumOfDays)),'-day mean)'];
                end                
                yyaxis(app.UIAxes,'left');
                bar(app.UIAxes,app.AvgDailyCases(index,:));
                title(app.UIAxes,figure_title);                
                mx = max(app.AvgDailyCases(index,:));
                set(app.UIAxes,'YLim',[0,mx]);
                yyaxis(app.UIAxes,'right');
                plot(app.UIAxes,app.AvgDailyDeaths(index,:));
                mx = max(app.AvgDailyDeaths(index,:));
                set(app.UIAxes,'YLim',[0,mx]);                   
            end

            len = length(app.CovidData.Dates);    
            set(app.UIAxes,'XLim',[0,len],'XTick',[0:90:len,len],'XTickLabel',app.CovidData.DatesMonthsYears);
            app.UIAxes.XGrid = 'on'; app.UIAxes.YGrid = 'on';
        end
        
        function res = find_state_index(app)
            ii = 0;
            for index = 1:length(app.CovidData.StateNames)
                if isequal(length(app.CovidData.StateNames{index}),1)
                    ii = ii + 1;
                    if strcmp(app.CovidData.CountryNames{index},app.CountryListBox.Value)
                        res = ii;
                        return;
                    end
                else
                    for index_2 = 1:length(app.CovidData.StateNames{index})
                        if strcmp(app.CovidData.StateNames{index}(index_2),app.StateorRegionListBox.Value) && strcmp(app.CovidData.CountryNames{index},app.CountryListBox.Value)
                            ii = ii + 1;
                            res = ii;
                            return;
                        else
                            ii = ii + 1;
                        end
                    end
                end
            end
        end
        
        function find_average_cases_deaths(app, avg)
            app.AvgNumOfDays = avg;
            if isequal(avg,1)
                app.IsAverage = 0;
                app.AvgCumulatedCases = app.CovidData.CumulativeCases;
                app.AvgCumulatedDeaths = app.CovidData.CumulativeDeaths;
                app.AvgDailyCases = app.CovidData.DailyCases;
                app.AvgDailyDeaths = app.CovidData.DailyDeaths;
            else
                app.IsAverage = 1;
                app.AvgCumulatedCases(1:(avg-1)) = app.CovidData.CumulativeCases(1:(avg-1));
                app.AvgCumulatedDeaths(1:(avg-1)) = app.CovidData.CumulativeDeaths(1:(avg-1));
                app.AvgDailyCases(1:(avg-1)) = app.CovidData.DailyCases(1:(avg-1));
                app.AvgDailyDeaths(1:(avg-1)) = app.CovidData.DailyDeaths(1:(avg-1));
                for index = avg:size(app.CovidData.CumulativeCases,2)
                    app.AvgCumulatedCases(:,index) = sum(app.CovidData.CumulativeCases(:,(index-avg+1):(index)),2) / avg;
                    app.AvgCumulatedDeaths(:,index) = sum(app.CovidData.CumulativeDeaths(:,(index-avg+1):(index)),2) / avg;
                    app.AvgDailyCases(:,index) = sum(app.CovidData.DailyCases(:,(index-avg+1):(index)),2) / avg;
                    app.AvgDailyDeaths(:,index) = sum(app.CovidData.DailyDeaths(:,(index-avg+1):(index)),2) / avg;
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            covid = load('covid_data.mat');
            covid_data = covid.covid_data;
            app.CovidData = covid_database(covid_data);
            app.State_Index = 1; app.IsAverage = 0;
            app.CountryListBox.Items = (app.CovidData.CountryNames)';
            app.CountryListBox.Value = app.CovidData.CountryNames(app.State_Index);
            app.StateorRegionListBox.Items = app.CovidData.StateNames{app.State_Index}; app.StateorRegionListBox.Value = 'All';
            app.AvgCumulatedCases = zeros(size(app.CovidData.CumulativeCases));
            app.AvgCumulatedDeaths = zeros(size(app.CovidData.CumulativeDeaths));
            app.AvgDailyCases = zeros(size(app.CovidData.DailyCases));
            app.AvgDailyDeaths = zeros(size(app.CovidData.DailyDeaths));
            find_average_cases_deaths(app, 1);
            plot_data(app);
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            index = find_country_index(app);
            app.Country_Index = index;
            app.StateorRegionListBox.Items = app.CovidData.StateNames{index};
            app.StateorRegionListBox.Value = app.StateorRegionListBox.Items(1);
            app.State_Index = find_state_index(app);
            plot_data(app);
        end

        % Selection changed function: DatatoplotButtonGroup
        function DatatoplotButtonGroupSelectionChanged(app, event)
              plot_data(app);
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
              plot_data(app);
        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
            app.State_Index = find_state_index(app);
            plot_data(app);
        end

        % Value changed function: AveragedofdaysSlider
        function AveragedofdaysSliderValueChanged(app, event)
            app.AveragedofdaysSlider.Value = round(app.AveragedofdaysSlider.Value);
            find_average_cases_deaths(app,app.AveragedofdaysSlider.Value);
            plot_data(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COVID19DataVisualizationUIFigure = uifigure('Visible', 'off');
            app.COVID19DataVisualizationUIFigure.Name = 'COVID-19 Data Visualization (Data Source: Johns Hopkins University)';
            app.UIAxes = uiaxes(app.COVID19DataVisualizationUIFigure);
            title(app.UIAxes, 'Title')
            app.AveragedofdaysLabel = uilabel(app.COVID19DataVisualizationUIFigure);
            app.AveragedofdaysLabel.Text = {'Averaged '; '# of days'};
            app.AveragedofdaysSlider = uislider(app.COVID19DataVisualizationUIFigure);
            app.AveragedofdaysSlider.Limits = [1 15];
            app.AveragedofdaysSlider.ValueChangedFcn = createCallbackFcn(app, @AveragedofdaysSliderValueChanged, true);
            app.AveragedofdaysSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15];
            app.AveragedofdaysSlider.Value = 1;
            app.DatatoplotButtonGroup = uibuttongroup(app.COVID19DataVisualizationUIFigure);
            app.DatatoplotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoplotButtonGroupSelectionChanged, true);
            app.DatatoplotButtonGroup.Title = 'Data to plot';
            app.CasesButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.COVID19DataVisualizationUIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.CountryLabel = uilabel(app.COVID19DataVisualizationUIFigure);
            app.CountryLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.COVID19DataVisualizationUIFigure);
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.StateorRegionListBoxLabel = uilabel(app.COVID19DataVisualizationUIFigure);
            app.StateorRegionListBoxLabel.Text = {'State or'; 'Region:'};
            app.StateorRegionListBox = uilistbox(app.COVID19DataVisualizationUIFigure);
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            % Show the figure after all components are created
            app.COVID19DataVisualizationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = covid_visual

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COVID19DataVisualizationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COVID19DataVisualizationUIFigure)
        end
    end
end